classdef OPSCEAannotate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ScorerinfoPanel              matlab.ui.container.Panel
        TrainingLevelDropDownLabel   matlab.ui.control.Label
        TrainingLevelDropDown        matlab.ui.control.DropDown
        YourfirstnameLabel           matlab.ui.control.Label
        YourfirstnameEditField       matlab.ui.control.EditField
        IdentifySeizureOnsetZoneSOZPanel  matlab.ui.container.Panel
        AnnotateButton               matlab.ui.control.Button
        ClearLASTannotationButton    matlab.ui.control.Button
        ClearALLannotationsButton    matlab.ui.control.Button
        WhetherCompleteLabel         matlab.ui.control.Label
        StatusofthisSeizureLabel     matlab.ui.control.Label
        SeizureDropDownLabel         matlab.ui.control.Label
        SeizureDropDown              matlab.ui.control.DropDown
        TextDescriptionLabel         matlab.ui.control.Label
        SeizureDescriptionEditField  matlab.ui.control.EditField
        NextSeizureButton            matlab.ui.control.Button
        OPSCEAAnnotatorLabel         matlab.ui.control.Label
        ConfidencelevelPanel         matlab.ui.container.Panel
        DropDownSOZcount             matlab.ui.control.DropDown
        ConfidenceLabel              matlab.ui.control.Label
        DropDownSOZlocal             matlab.ui.control.DropDown
        SOZcountLabel                matlab.ui.control.Label
        SOZlocalizationLabel         matlab.ui.control.Label
        SAVEButton                   matlab.ui.control.Button
        RefreshPageButton            matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        sz={}; % List of seizures (also works as filenames for JPGs)
        szcomplete %
        SOZs={}; % row for every seizure (multiple polygons in a nested cell
        whichsz % which seizure (#, in order) are we currently on?
        confidencecount % Description
        confidencelocal % Description
        confidencecomplete % Description
        TrainingLevel % Description
        currentdirectory % Description
        scorerID % Description
        sztextdescription = {}; %List of SOZ text descriptions
        sztextdescriptioncomplete %
        timesaved=cell(21,1);
     
    end

    methods (Access = private)
        
        function getszlist(app)
            
%             app.sz={
%                     %'EC50_01'
%                     %'TD50_01'
%                     'EC58_01'
%                     'TD58_01'
%                     %'EC60_01'
%                     %'TD60_01'
%                     %'EC99_01'
%                     %'TD99_01'
%                     %'EC118_01'
%                     %'TD118_01'
%                     'EC135_01'
%                     'TD135_01'
%                     'EC143_01'
%                     'TD143_01'
%                     'EC150_01'
%                     'TD150_01'
%                     %'EC151_01'
%                     %'TD151_01'
%                     'EC171_01'
%                     'TD171_01'
%                     'EC172_01'
%                     'TD172_01'
%                     'EC179_01'
%                     'TD179_01'
%                     %'EC205_01'
%                     %'TD205_01'
%                     %'EC218_01'
%                     %'TD218_01'
%                     'EC223_01'
%                     'TD223_01'
%                     'EC225_01'
%                     'TD225_01'
%                     'EC244_01'
%                     'TD244_01'
%                     %'EC245_01'
%                     %'TD245_01'
%                     %'EC251_01'
%                     %'TD251_01'
%                     %'EC251_02'
%                     %'TD251_02'
%                                };
            
            app.sz={
                    '1'
                    '2'
                    '3'
                    '4'
                    '5'
                    '6'
                    '7'
                    '8'
                    '9'
                    '10'
                    '11'
                    '12'
                    '13'
                    '14'
                    '15'
                    '16'
                    '17'
                    '18'
                    '19'
                    '20'
                    '21'
                               };
            app.sztextdescription={
                    'insert text'
                    'insert text'
                    'insert text'
                    'insert text'
                    'insert text'
                    'insert text'
                    'insert text'
                    'insert text'
                    'insert text'
                    'insert text'
                    'insert text'
                    'insert text'
                    'insert text'
                    'insert text'
                    'insert text'
                    'insert text'
                    'insert text'
                    'insert text'
                    'insert text'
                    'insert text'
                    'insert text'
                };
            
        
                app.szcomplete=false(size(app.sz));
                app.confidencecount=repmat({'Click to rate'},size(app.sz,1),1);
                app.confidencelocal=repmat({'Click to rate'},size(app.sz,1),1);
                app.confidencecomplete=false(size(app.sz));
                app.sztextdescriptioncomplete=false(size(app.sz));
             
                
        end
        
        function UpdateStatus(app)
            
            % status of polygons
            if app.szcomplete(app.whichsz) && ~isempty(app.SOZs{app.whichsz,1}) % double check to make sure there is data for this seizure
                app.WhetherCompleteLabel.BackgroundColor=[0 1 0]; %Mark green if done
                app.WhetherCompleteLabel.Text='Complete';
            else
                app.WhetherCompleteLabel.BackgroundColor=[.8 .8 .8]; %Mark red if not yet done
                app.WhetherCompleteLabel.Text='Not Complete';
            end
            
            % status of confidence rating
            if app.confidencecomplete(app.whichsz) && ~isempty(app.SOZs{app.whichsz,1}) % double check to make sure there is data for this seizure
                app.ConfidenceLabel.BackgroundColor=[0 1 0]; %Mark green if done
                app.ConfidenceLabel.Text='Complete';
            else
                app.ConfidenceLabel.BackgroundColor=[.8 .8 .8]; %Mark red if not yet done
                app.ConfidenceLabel.Text='Not Complete';
            end
            app.DropDownSOZcount.Value=app.confidencecount{app.whichsz};
            app.DropDownSOZlocal.Value=app.confidencelocal{app.whichsz};
            
            %status of text description field
            app.SeizureDescriptionEditField.Value = app.sztextdescription{app.whichsz}
            value = app.sztextdescription{app.whichsz};
            resultempty = strcmp(value, '');
            result = strcmp(value, 'insert text'); %creates a variable result and compares if the variable is the same as 'insert text' 
            if ~result && ~resultempty
                app.TextDescriptionLabel.BackgroundColor = [0 1 0] %Mark green if done
            else
                app.TextDescriptionLabel.BackgroundColor = [.8 .8 .8]; %Mark red if not yet done
            end
           
         
        end


        %% PLOT THE IMAGE (+ OVERLAY ANY POLYGONS)
        function PlotImage(app)
          im=imread([app.currentdirectory 'Images for Study/' app.sz{app.whichsz} '_Image.png']); % pull image from file
          imshow(im, 'parent', app.UIAxes) %displays the brain image
          if ~isempty(app.SOZs)
            polyg=app.SOZs{app.whichsz}; 
            for i=1:length(polyg)
                p=polyg{i};
                if ~isempty(p)
                    hold(app.UIAxes, 'on' );
                    fill(app.UIAxes,p(:,1),p(:,2),[.5 0 1],'FaceAlpha',0.3)
                end
            end
          end
          hold(app.UIAxes, 'off' );
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: YourfirstnameEditField
        function YourfirstnameEditFieldValueChanged(app, event)
            value = app.YourfirstnameEditField.Value;
            upath=regexp(userpath,'/','split'); upath=['/' upath{2} '/' upath{3} '/'];
%             app.currentdirectory=[upath '/Users/jkleen/Box/KLEENLAB/Ebenezer/OPSCEAannotate'];     %path for .m program files
%             app.currentdirectory=[upath 'Box/KLEENLAB/Ebenezer/OPSCEA Study/'];     %path for .m program files
%             app.currentdirectory=[upath 'Library/CloudStorage/Box-Box/KLEENLAB/Ebenezer/OPSCEA Study/'];     %path for .m program files
            app.currentdirectory=[fileparts(which('OPSCEAannotate')) '/'];     %path for .m program files

            cd(app.currentdirectory)
            getszlist(app) %enters list of seizures into the app.sz variable
            app.SOZs=cell(size(app.sz,1),1);
            % load data from this scorer, if available
            if exist([app.currentdirectory 'Data/' value])==7 %check if path exists for this scorer
                filepath=[app.currentdirectory 'Data/' value '/' [value '_SOZdata.mat']];
              if exist(filepath)
                load(filepath)
                app.sz=sz;
                app.szcomplete=szcomplete;
                app.SOZs=SOZs;             
                app.confidencecount=confidencecount;
                app.confidencelocal=confidencelocal;
                app.confidencecomplete=confidencecomplete;                
                app.TrainingLevel=traininglevel;    app.TrainingLevelDropDown.Value=app.TrainingLevel;
                app.scorerID=scorerID;
                app.sztextdescriptioncomplete=sztextdescriptioncomplete;
                app.sztextdescription=sztextdescription;
                app.timesaved=sztimesaved;
                
              else
                  c=cell(1,1); c={c}; % a cell within a cell
                  app.SOZs=repmat(c,size(app.sz,1),1);% create new empty cell array
                  %app.szcomplete=false(40,1);
              end
            else
                mkdir([app.currentdirectory  'Data/' value])
            end
            removeToolbarExplorationButtons(app.UIAxes)
            app.UIAxes.Toolbar.Visible='off'; app.SeizureDropDown.Enable = 'off';
            disableDefaultInteractivity(app.UIAxes)
            szstart=find(sum([app.szcomplete app.confidencecomplete app.sztextdescriptioncomplete],2)<3,1);
            app.SeizureDropDown.Value = num2str(szstart);
            app.whichsz=szstart;
            SeizureDropDownValueChanged(app)
            
        end

        % Value changed function: SeizureDropDown
        function SeizureDropDownValueChanged(app, event)
            value = app.SeizureDropDown.Value; ; 
            if any(app.szcomplete); SAVEButtonPushed(app); end
            if isempty(app.sz); getszli1st(app); end
            app.whichsz=find(strcmpi(app.sz,value));
            PlotImage(app)
            UpdateStatus(app) %updates the color of the "Status of this Seizure" on the UI
        end

        % Button pushed function: AnnotateButton
        function AnnotateButtonPushed(app, event)
            app.AnnotateButton.BackgroundColor=[1 1 0];
            SeizureDropDownValueChanged(app) %make sure displayed image is up to date
            SOZ = drawpolygon(app.UIAxes,'FaceAlpha',0); %draw polygon on image
            app.AnnotateButton.BackgroundColor=[0.9600 0.9600 0.9600];
            xy={SOZ.Position};
            if app.szcomplete(app.whichsz)==true %if there is already at least one polygon for this image,
                polyg=app.SOZs{app.whichsz};
                polyg=[polyg xy]; %tack on the most recent polygon coordinates
                app.SOZs{app.whichsz}=polyg;
            else %if no data for this image thus far
                app.SOZs{app.whichsz}=xy; % enter this polygon as new data
                app.szcomplete(app.whichsz)=true; % mark it as complete
            end
            UpdateStatus(app)
            PlotImage(app)
        end

        % Drop down opening function: SeizureDropDown
        function SeizureDropDownOpening(app, event)
            
        end

        % Button pushed function: ClearLASTannotationButton
        function ClearLASTannotationButtonPushed(app, event)
            if app.szcomplete(app.whichsz)==true %if there is already at least one polygon for this image,
                polyg=app.SOZs{app.whichsz};
                if length(polyg)>1 %if more than one polygon
                    polyg(end)=[]; %remove the most recent polygon coordinates
                    app.SOZs{app.whichsz}=polyg;
                elseif length(polyg)==1 %if just one polygon, reset the entry to empty (as in "ClearALLannotationsButtonPushed" below)
                    app.SOZs{app.whichsz}={[]};
                    app.confidencecount{app.whichsz}=app.DropDownSOZcount.Items(1);
                    app.confidencelocal{app.whichsz}=app.DropDownSOZlocal.Items(1);
                    app.confidencecomplete(app.whichsz)=0;
                    app.szcomplete(app.whichsz)=0;
                    app.sztextdescription{app.whichsz} = 'insert text';
                end
            UpdateStatus(app)
            PlotImage(app)
            else msgbox('No polygons drawn yet for this image') %if no data for this image thus far
            end
        end

        % Button pushed function: ClearALLannotationsButton
        function ClearALLannotationsButtonPushed(app, event)
            if app.szcomplete(app.whichsz)==true %if there is already at least one polygon for this image,
                app.SOZs{app.whichsz}={[]};
            else msgbox('No polygons drawn yet for this image') %if no data for this image thus far
            end
            app.confidencecount{app.whichsz}=app.DropDownSOZcount.Items(1);
            app.confidencelocal{app.whichsz}=app.DropDownSOZlocal.Items(1);
            app.confidencecomplete(app.whichsz)=0;
            app.szcomplete(app.whichsz)=0;
            app.sztextdescription{app.whichsz} = 'insert text';
            app.SeizureDescriptionEditField.Value= 'insert text'; drawnow;
            UpdateStatus(app)
            PlotImage(app)
        end

        % Value changed function: TrainingLevelDropDown
        function TrainingLevelDropDownValueChanged(app, event)
            value = app.TrainingLevelDropDown.Value;
            app.TrainingLevel=value;
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            if any(app.szcomplete)
                sz=app.sz;
                szcomplete=app.szcomplete;
                SOZs=app.SOZs;
                confidencecount=app.confidencecount;
                confidencelocal=app.confidencelocal;
                confidencecomplete=app.confidencecomplete;
                traininglevel=app.TrainingLevelDropDown.Value;
                scorerID=app.YourfirstnameEditField.Value;
                sztextdescription=app.sztextdescription;
                sztextdescriptioncomplete=app.sztextdescriptioncomplete;
                sztimesaved=app.timesaved;
               
                save([app.currentdirectory 'Data/' scorerID '/' [scorerID '_SOZdata.mat']],'sz','szcomplete','SOZs','confidencecount', 'confidencelocal','confidencecomplete','traininglevel','scorerID','sztextdescription','sztextdescriptioncomplete', 'sztimesaved')
                c=app.SAVEButton.BackgroundColor; 
                app.SAVEButton.BackgroundColor=[.75 .5 1]; app.SAVEButton.Text='Data saved!'; 
                pause(1); 
                app.SAVEButton.BackgroundColor=c; app.SAVEButton.Text='SAVE'; 
                
            else
                msgbox('No data entered yet')
            end
            
        end

        % Value changed function: SeizureDescriptionEditField
        function SeizureDescriptionEditFieldValueChanged(app, event)
            value = app.SeizureDescriptionEditField.Value;
            if strcmpi(value,'') || strcmpi(value, 'insert text')
                app.sztextdescription{app.whichsz}=value;
                app.sztextdescriptioncomplete(app.whichsz)=0;
            else
                app.sztextdescription{app.whichsz}=app.SeizureDescriptionEditField.Value;
                app.sztextdescriptioncomplete(app.whichsz)=1;
      
            end 
            
            SAVEButtonPushed(app); UpdateStatus(app);
            
             
        end

        % Button pushed function: NextSeizureButton
        function NextSeizureButtonPushed(app, event)
            function ChangedSeizureDropDown(app) % Function to update the drop-down menu when the 'Next Seizure' button is clicked
            if any(app.szcomplete); SAVEButtonPushed(app); end % Save current data before moving to the next seizure
             items = app.SeizureDropDown.Items; % Get the current items in the drop-down menu
             currentValue = app.SeizureDropDown.Value; % Get the current value of the drop-down menu 
             index = find(strcmp(items, currentValue)); % Find the index of the current value in the list of items
             index = index + 1; % Increment the index by 1
            if index <= length(items) % Check if n+1 is within the range of the items
                app.SeizureDropDown.Value = items{index}; % Set the value of the drop-down menu to the n+1th item
            else
                uialert(app.UIFigure, 'Thank you for completing this study', 'Missing Information!'); % Display an error message if n+1 is out of range
            end
            end
            
           value = app.sztextdescription{app.whichsz}; % Get current value of the seizure text description field
           currentsz=app.SeizureDropDown.Value % Get current value of the seizure drop-down menu
           if app.szcomplete(app.whichsz) && app.confidencecomplete(app.whichsz) && app.sztextdescriptioncomplete(app.whichsz)  % Check if the current seizure has been annotated, a confidence level has been provided, and a text description has been inputted
              app.timesaved{app.whichsz}=datestr(datetime);
               SAVEButtonPushed(app) % Save current data before moving to the next seizure
              app.whichsz=find(strcmpi(app.sz,(num2str(str2num(currentsz)+1)))); %changes whichsz to the index of the next seizure
            PlotImage(app) %plots the next seizure 
            ChangedSeizureDropDown(app) %Update the drop-down menu to show the next seizure
            UpdateStatus(app) %updates the app based on the next seizure
           else
               uialert(app.UIFigure, 'Please finish annotating the seizure, providing a text description, and inputing your confidence level(s) to continue', 'Missing Information!'); % Display an alert message if the current seizure has not been fully annotated, provided with a confidence level, or inputted with a text description
           end
        end

        % Button pushed function: RefreshPageButton
        function RefreshPageButtonPushed(app, event)
            SeizureDropDownValueChanged(app)
            PlotImage(app)
            UpdateStatus(app)
        end

        % Value changed function: DropDownSOZcount
        function DropDownSOZcountValueChanged(app, event)
            value = app.DropDownSOZcount.Value;
            app.confidencecount{app.whichsz}=value;
            if ~strcmpi(value,app.DropDownSOZcount.Items(1)) && (~isempty(app.confidencecount{app.whichsz}) && ~strcmpi(app.confidencelocal{app.whichsz},'Click to rate'))
                app.confidencecomplete(app.whichsz)=1;
            end
            UpdateStatus(app)
            SAVEButtonPushed(app)
        end

        % Value changed function: DropDownSOZlocal
        function DropDownSOZlocalValueChanged(app, event)
            value = app.DropDownSOZlocal.Value;
            app.confidencelocal{app.whichsz}=value;
            if ~strcmpi(value,app.DropDownSOZlocal.Items(1)) && (~isempty(app.confidencelocal{app.whichsz}) && ~strcmpi(app.confidencecount{app.whichsz},'Click to rate'))
                app.confidencecomplete(app.whichsz)=1;
            end
            UpdateStatus(app)
            SAVEButtonPushed(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ScorerinfoPanel = uipanel(app.UIFigure);
            app.ScorerinfoPanel.Title = 'Scorer info';
            app.TrainingLevelDropDownLabel = uilabel(app.ScorerinfoPanel);
            app.TrainingLevelDropDownLabel.Text = 'Training Level';
            app.TrainingLevelDropDown = uidropdown(app.ScorerinfoPanel);
            app.TrainingLevelDropDown.Items = {'Student', 'Resident', 'Fellow', 'Attending'};
            app.TrainingLevelDropDown.ValueChangedFcn = createCallbackFcn(app, @TrainingLevelDropDownValueChanged, true);
            app.TrainingLevelDropDown.Value = 'Student';
            app.YourfirstnameLabel = uilabel(app.ScorerinfoPanel);
            app.YourfirstnameLabel.Text = 'Your first name:';
            app.YourfirstnameEditField = uieditfield(app.ScorerinfoPanel, 'text');
            app.YourfirstnameEditField.ValueChangedFcn = createCallbackFcn(app, @YourfirstnameEditFieldValueChanged, true);
            app.IdentifySeizureOnsetZoneSOZPanel = uipanel(app.UIFigure);
            app.IdentifySeizureOnsetZoneSOZPanel.Title = 'Identify Seizure-Onset Zone (SOZ)';
            app.AnnotateButton = uibutton(app.IdentifySeizureOnsetZoneSOZPanel, 'push');
            app.AnnotateButton.ButtonPushedFcn = createCallbackFcn(app, @AnnotateButtonPushed, true);
            app.AnnotateButton.Text = 'Annotate';
            app.ClearLASTannotationButton = uibutton(app.IdentifySeizureOnsetZoneSOZPanel, 'push');
            app.ClearLASTannotationButton.ButtonPushedFcn = createCallbackFcn(app, @ClearLASTannotationButtonPushed, true);
            app.ClearLASTannotationButton.Text = 'Clear LAST annotation';
            app.ClearALLannotationsButton = uibutton(app.IdentifySeizureOnsetZoneSOZPanel, 'push');
            app.ClearALLannotationsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearALLannotationsButtonPushed, true);
            app.ClearALLannotationsButton.Text = 'Clear ALL annotations';
            app.WhetherCompleteLabel = uilabel(app.IdentifySeizureOnsetZoneSOZPanel);
            app.WhetherCompleteLabel.Text = 'Not Complete';
            app.StatusofthisSeizureLabel = uilabel(app.IdentifySeizureOnsetZoneSOZPanel);
            app.StatusofthisSeizureLabel.Text = 'Status of this Seizure:';
            app.SeizureDropDownLabel = uilabel(app.IdentifySeizureOnsetZoneSOZPanel);
            app.SeizureDropDownLabel.Text = 'Seizure #:';
            app.SeizureDropDown = uidropdown(app.IdentifySeizureOnsetZoneSOZPanel);
            app.SeizureDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21'};
            app.SeizureDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SeizureDropDownOpening, true);
            app.SeizureDropDown.ValueChangedFcn = createCallbackFcn(app, @SeizureDropDownValueChanged, true);
            app.SeizureDropDown.Value = '1';
            app.TextDescriptionLabel = uilabel(app.IdentifySeizureOnsetZoneSOZPanel);
            app.TextDescriptionLabel.Text = 'Text Description:';
            app.SeizureDescriptionEditField = uieditfield(app.IdentifySeizureOnsetZoneSOZPanel, 'text');
            app.SeizureDescriptionEditField.ValueChangedFcn = createCallbackFcn(app, @SeizureDescriptionEditFieldValueChanged, true);
            app.NextSeizureButton = uibutton(app.IdentifySeizureOnsetZoneSOZPanel, 'push');
            app.NextSeizureButton.ButtonPushedFcn = createCallbackFcn(app, @NextSeizureButtonPushed, true);
            app.NextSeizureButton.Text = 'Next Seizure';
            app.OPSCEAAnnotatorLabel = uilabel(app.UIFigure);
            app.OPSCEAAnnotatorLabel.Text = 'OPSCEA Annotator';
            app.ConfidencelevelPanel = uipanel(app.UIFigure);
            app.ConfidencelevelPanel.Title = 'Confidence level';
            app.DropDownSOZcount = uidropdown(app.ConfidencelevelPanel);
            app.DropDownSOZcount.Items = {'Click to rate', 'Certain', 'Likely', 'Probably', 'Possibly', 'Uncertain'};
            app.DropDownSOZcount.ValueChangedFcn = createCallbackFcn(app, @DropDownSOZcountValueChanged, true);
            app.DropDownSOZcount.Value = 'Click to rate';
            app.ConfidenceLabel = uilabel(app.ConfidencelevelPanel);
            app.ConfidenceLabel.Text = 'Not Complete';
            app.DropDownSOZlocal = uidropdown(app.ConfidencelevelPanel);
            app.DropDownSOZlocal.Items = {'Click to rate', 'Certain', 'Likely', 'Probably', 'Possibly', 'Uncertain'};
            app.DropDownSOZlocal.ValueChangedFcn = createCallbackFcn(app, @DropDownSOZlocalValueChanged, true);
            app.DropDownSOZlocal.Value = 'Click to rate';
            app.SOZcountLabel = uilabel(app.ConfidencelevelPanel);
            app.SOZcountLabel.Text = 'SOZ count';
            app.SOZlocalizationLabel = uilabel(app.ConfidencelevelPanel);
            app.SOZlocalizationLabel.Text = 'SOZ localization';
            app.SAVEButton = uibutton(app.UIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.RefreshPageButton = uibutton(app.UIFigure, 'push');
            app.RefreshPageButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshPageButtonPushed, true);
            app.RefreshPageButton.Text = 'Refresh Page';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OPSCEAannotate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end